classdef Lab04 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CloseButton        matlab.ui.control.Button
        OpenButton         matlab.ui.control.Button
        NoiseSPButton      matlab.ui.control.Button
        NoiseGaussButton   matlab.ui.control.Button
        AverageButton      matlab.ui.control.Button
        GaussianButton     matlab.ui.control.Button
        PrewittButton      matlab.ui.control.Button
        SobelButton        matlab.ui.control.Button
        LaplacianButton    matlab.ui.control.Button
        LogButton          matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes5            matlab.ui.control.UIAxes
        UIAxes6            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        image
        image_noiseSP
        image_noiseGauss
    end

    methods (Access = private)

        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        function OpenButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            app.image = imread(fullfile(path, file));
            imshow(app.image, 'Parent', app.UIAxes);
        end

        function NoiseSPButtonPushed(app, event)
            app.image_noiseSP = imnoise(app.image, 'salt & pepper');
            imshow(app.image_noiseSP, 'Parent', app.UIAxes2);
        end

        function NoiseGaussButtonPushed(app, event)
            app.image_noiseGauss = imnoise(app.image, 'gaussian');
            imshow(app.image_noiseGauss, 'Parent', app.UIAxes3);
        end

        function AverageButtonPushed(app, event)
            f = fspecial('average');
            imshow(imfilter(app.image, f), 'Parent', app.UIAxes4);
            imshow(imfilter(app.image_noiseSP, f), 'Parent', app.UIAxes5);
            imshow(imfilter(app.image_noiseGauss, f), 'Parent', app.UIAxes6);
        end

        function GaussianButtonPushed(app, event)
            f = fspecial('gaussian');
            imshow(imfilter(app.image, f), 'Parent', app.UIAxes4);
            imshow(imfilter(app.image_noiseSP, f), 'Parent', app.UIAxes5);
            imshow(imfilter(app.image_noiseGauss, f), 'Parent', app.UIAxes6);
        end

        function PrewittButtonPushed(app, event)
            f = fspecial('prewitt');
            imshow(imfilter(im2double(app.image), f), 'Parent', app.UIAxes4);
            imshow(imfilter(im2double(app.image_noiseSP), f), 'Parent', app.UIAxes5);
            imshow(imfilter(im2double(app.image_noiseGauss), f), 'Parent', app.UIAxes6);
        end

        function SobelButtonPushed(app, event)
            f = fspecial('sobel');
            imshow(imfilter(im2double(app.image), f), 'Parent', app.UIAxes4);
            imshow(imfilter(im2double(app.image_noiseSP), f), 'Parent', app.UIAxes5);
            imshow(imfilter(im2double(app.image_noiseGauss), f), 'Parent', app.UIAxes6);
        end

        function LaplacianButtonPushed(app, event)
            f = fspecial('laplacian');
            imshow(imfilter(im2double(app.image), f), 'Parent', app.UIAxes4);
            imshow(imfilter(im2double(app.image_noiseSP), f), 'Parent', app.UIAxes5);
            imshow(imfilter(im2double(app.image_noiseGauss), f), 'Parent', app.UIAxes6);
        end

        function LogButtonPushed(app, event)
            f = fspecial('log');
            imshow(imfilter(im2double(app.image), f), 'Parent', app.UIAxes4);
            imshow(imfilter(im2double(app.image_noiseSP), f), 'Parent', app.UIAxes5);
            imshow(imfilter(im2double(app.image_noiseGauss), f), 'Parent', app.UIAxes6);
        end
    end

    methods (Access = private)

        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Lab04 - Image Filtering';
            buttonWidth = 130;
            buttonHeight = 30;
            buttonX = 20;
            startY = 650;
            spacing = 40;
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.NoiseSPButton = uibutton(app.UIFigure, 'push');
            app.NoiseSPButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseSPButtonPushed, true);
            app.NoiseSPButton.Text = 'Noise S&P';
            app.NoiseGaussButton = uibutton(app.UIFigure, 'push');
            app.NoiseGaussButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseGaussButtonPushed, true);
            app.NoiseGaussButton.Text = 'Noise Gauss';
            app.AverageButton = uibutton(app.UIFigure, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.GaussianButton = uibutton(app.UIFigure, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.LaplacianButton = uibutton(app.UIFigure, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            axesX = 180;
            axesWidth = 320;
            axesHeight = 280;
            axesSpacing = 340;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Salt & Pepper Noise');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Gaussian Noise');
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Filtered Original');
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Filtered S&P');
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Filtered Gaussian');
            app.UIFigure.Visible = 'on';
        end
    end

    methods (Access = public)

        function app = Lab04
            createComponents(app)
            registerApp(app, app.UIFigure)
            if nargout == 0
                clear app
            end
        end

        function delete(app)
            delete(app.UIFigure)
        end
    end
end